classdef TMCL_Matlab_App_TMCM_6212 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PortconnectionTab               matlab.ui.container.Tab
        ConnectionStatusText            matlab.ui.control.TextArea
        ConnectStatus                   matlab.ui.control.Lamp
        ConnectionstatustocompottLabel  matlab.ui.control.Label
        ConnectonButton                 matlab.ui.control.StateButton
        BaudrateDD                      matlab.ui.control.DropDown
        BaudrateDropDownLabel           matlab.ui.control.Label
        PortselectDD                    matlab.ui.control.DropDown
        PortselectDropDownLabel         matlab.ui.control.Label
        TextWindow                      matlab.ui.control.TextArea
        TextWindowTextAreaLabel         matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        SearchallavailableCOMportsLabel  matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
    end

    
    properties (Access = public)
        COM_obj           % Serial object representing the selected COM port
        file_path         % Path to the text file containing a set of commands
        ADDRESS           % Address of the module
        MNEMONICS         % List of all available mnemonic encoding in decimal for the commands numbers
        COMMANDS          % List of all available commands with their parameters
        TYPES             % Mnemonic encoding in decimal for the types numbers
        PARAMETERS        % Mnemonic encoding in decimal for the available axis parameters
        MOTOR_ID          % Number of the motor
        type_enabled      % Boolean value: '1' => type field on, '0' => type field off
        run_file          % Boolean value: '1' => start sending instructions, '0' => stop sending instructions
        trigger           % Boolean value: '1' => start capturing data, '0' => stop capturing data
        save_to_file      % Boolean value: '1' => start saving data, '0' => stop saving data
        MAT_file          % MAT file containing the measured data
        position_plot     % Plot line containing the position value
        velocity_plot     % Plot line containing the velocity value
        acceleration_plot % Plot line containing the acceleration value
        refresh_time      % Delay before refreshing the plots
        window_time       % Shifting window on the measured data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.TextWindow.Value = '';
            % Display the list of all available COM ports on the computer
            app.PortselectDD.Items = find_ports();
            app.ConnectStatus.Color  = [1 1 0];
            app.ConnectionStatusText.Value = 'Searching...';
            pause(1);
            app.ConnectionStatusText.Value = 'Select port';
        end

        % Value changed function: ConnectonButton
        function ConnectonButtonValueChanged(app, event)
            % Constants
            CONNECTION_OK = 'The COM port connection has been established successfully.';
            CONNECTION_NO = 'No available COM port has been selected. Click on the ''Search'' button to get the status of the available COM ports.';

            if strcmp(app.ConnectonButton.Text, 'Disconnected')
                % Clear the output error log text area
                app.TextWindow.Value = '';

                % Creation of the serial object
                try
                    % Start the serial port session
                    app.COM_obj = serialport(app.PortselectDD.Value, 'BaudRate', str2double(app.BaudrateDD.Value), 'DataBits', 8, 'Parity', 'none', 'StopBits', 1, 'FlowControl', 'none', 'Timeout', 10);
                    app.TextWindow.FontColor = [0 0.75 0];
                    %%% app.CaptureData.Enable      = 'on';
                    app.TextWindow.Value     = CONNECTION_OK;
                    app.ConnectStatus.Color  = [0 1 0];
                    app.ConnectonButton.Text       = 'Connected'; % Update button text
                    app.ConnectionStatusText.Value = 'Connected';
                catch
                    app.TextWindow.FontColor = [0.75 0 0];
                    %%% app.CaptureData.Enable      = 'off';
                    app.TextWindow.Value     = CONNECTION_NO;
                    app.ConnectStatus.Color  = [1 0 0];
                    app.ConnectionStatusText.Value = 'Not connected';
                end
            elseif strcmp(app.SerialButton.Text, 'Connected')
                app.TextWindow.FontColor = [0 0.75 0];
                app.TextWindow.Value     = 'The COM port connection has been closed successfully.';
                app.startupFcn();
                %%% app.CaptureData.Enable      = 'off';
                app.ConnectStatus.Color  = [1 0 0];
                app.ConnectonButton.Text       = 'Disconnected'; % Update button text
                app.ConnectionStatusText.Value = 'Not connected';
            end
            %%app.FileLogText.Value = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PortconnectionTab = uitab(app.TabGroup);
            app.PortconnectionTab.Title = 'Port connection';
            app.SearchallavailableCOMportsLabel = uilabel(app.PortconnectionTab);
            app.SearchallavailableCOMportsLabel.Text = 'Search all available COM ports:';
            app.SearchButton = uibutton(app.PortconnectionTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.TextWindowTextAreaLabel = uilabel(app.PortconnectionTab);
            app.TextWindowTextAreaLabel.Text = 'Text Window';
            app.TextWindow = uitextarea(app.PortconnectionTab);
            app.PortselectDropDownLabel = uilabel(app.PortconnectionTab);
            app.PortselectDropDownLabel.Text = 'Port select: ';
            app.PortselectDD = uidropdown(app.PortconnectionTab);
            app.BaudrateDropDownLabel = uilabel(app.PortconnectionTab);
            app.BaudrateDropDownLabel.Text = 'Baud rate:';
            app.BaudrateDD = uidropdown(app.PortconnectionTab);
            app.BaudrateDD.Items = {'4800', '9600', '14400', '19200', '38400', '57600', '76800', '115200', '230400', '921600'};
            app.BaudrateDD.Value = '9600';
            app.ConnectonButton = uibutton(app.PortconnectionTab, 'state');
            app.ConnectonButton.ValueChangedFcn = createCallbackFcn(app, @ConnectonButtonValueChanged, true);
            app.ConnectonButton.Text = 'Disconnected';
            app.ConnectionstatustocompottLabel = uilabel(app.PortconnectionTab);
            app.ConnectionstatustocompottLabel.Text = 'Connection status to com port:';
            app.ConnectStatus = uilamp(app.PortconnectionTab);
            app.ConnectionStatusText = uitextarea(app.PortconnectionTab);
            app.ConnectionStatusText.Editable = 'off';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMCL_Matlab_App_TMCM_6212

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end