classdef Control_Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ConnectionTab              matlab.ui.container.Tab
        Connectionstatus           matlab.ui.control.Lamp
        ConnectionstatusLampLabel  matlab.ui.control.Label
        ConnectionLog              matlab.ui.control.TextArea
        ConnectionLogLabel         matlab.ui.control.Label
        ProcedureText              matlab.ui.control.TextArea
        ProcedureTextLabel         matlab.ui.control.Label
        DisconnectSerial           matlab.ui.control.Button
        ConnectSerial              matlab.ui.control.Button
        BaudrateSelection          matlab.ui.control.DropDown
        BaudrateSelectionLabel     matlab.ui.control.Label
        ComPortSelection           matlab.ui.control.DropDown
        ComPortSelectionLabel      matlab.ui.control.Label
        SearchPorts                matlab.ui.control.Button
        SerialPort                 matlab.ui.control.Label
        SerialPortProtocols        matlab.ui.control.Label
        DirectModeTab              matlab.ui.container.Tab
        InstructionStructure       matlab.ui.control.Label
        CodeText                   matlab.ui.control.TextArea
        CodeTextLabel              matlab.ui.control.Label
        GenerateInstruction        matlab.ui.control.Button
        Instruction                matlab.ui.control.Label
        MessageLogText             matlab.ui.control.TextArea
        MessageLogTextLabel        matlab.ui.control.Label
        StopCommand                matlab.ui.control.Button
        StartCommand               matlab.ui.control.Button
        WaitTimeSelection          matlab.ui.control.Spinner
        TimeSelectionLabel         matlab.ui.control.Label
        ResultText                 matlab.ui.control.TextArea
        ResultTextLabel            matlab.ui.control.Label
        ValueSelection             matlab.ui.control.Spinner
        ValueSelectionLabel        matlab.ui.control.Label
        MotorSelection             matlab.ui.control.DropDown
        MotorSelectionLabel        matlab.ui.control.Label
        TypeSelection              matlab.ui.control.DropDown
        TypeSelectionLabel         matlab.ui.control.Label
        CommandSelection           matlab.ui.control.DropDown
        CommandSelectionLabel      matlab.ui.control.Label
        AddressSelection           matlab.ui.control.DropDown
        AddressSelectionLabel      matlab.ui.control.Label
        MessageText                matlab.ui.control.TextArea
        MessageTextLabel           matlab.ui.control.Label
        SerialMessage              matlab.ui.control.Label
        FileModeTab                matlab.ui.container.Tab
        EmergencyStopLabel         matlab.ui.control.Label
        EmergencyStopCommand       matlab.ui.control.Button
        InstructionIDText          matlab.ui.control.TextArea
        InstructionIDLabel         matlab.ui.control.Label
        FileLogText                matlab.ui.control.TextArea
        FileLogLabel               matlab.ui.control.Label
        MeasurementText            matlab.ui.control.TextArea
        MeasurementTextLabel       matlab.ui.control.Label
        RunFile                    matlab.ui.control.Button
        InstructionsToRun          matlab.ui.control.Label
        FilePathText               matlab.ui.control.TextArea
        FilepathTextLabel          matlab.ui.control.Label
        BrowseFile                 matlab.ui.control.Button
        TextFileToProcess          matlab.ui.control.Label
        MonitoringTab              matlab.ui.container.Tab
        RadioButtons               matlab.ui.container.ButtonGroup
        ButtonNo                   matlab.ui.control.RadioButton
        ButtonYes                  matlab.ui.control.RadioButton
        CaptureData                matlab.ui.control.Button
        Datamonitoring             matlab.ui.control.Label
        AccelerationAxis           matlab.ui.control.UIAxes
        VelocityAxis               matlab.ui.control.UIAxes
        PositionAxis               matlab.ui.control.UIAxes
    end


    properties (Access = public)
        COM_obj           % Serial object representing the selected COM port
        file_path         % Path to the text file containing a set of commands
        ADDRESS           % Address of the module
        MNEMONICS         % List of all available mnemonic encoding in decimal for the commands numbers
        COMMANDS          % List of all available commands with their parameters
        TYPES             % Mnemonic encoding in decimal for the types numbers
        PARAMETERS        % Mnemonic encoding in decimal for the available axis parameters
        MOTOR_ID          % Number of the motor
        type_enabled      % Boolean value: '1' => type field on, '0' => type field off
        run_file          % Boolean value: '1' => start sending instructions, '0' => stop sending instructions
        trigger           % Boolean value: '1' => start capturing data, '0' => stop capturing data
        save_to_file      % Boolean value: '1' => start saving data, '0' => stop saving data
        MAT_file          % MAT file containing the measured data
        position_plot     % Plot line containing the position value
        velocity_plot     % Plot line containing the velocity value
        acceleration_plot % Plot line containing the acceleration value
        refresh_time      % Delay before refreshing the plots
        window_time       % Shifting window on the measured data
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Clean serial instances
            delete(instrfindall());
            % Get root directory
            app.file_path = pwd;
            % Set the address of the module
            app.ADDRESS = 1;
            app.AddressSelection.Items = {num2str(app.ADDRESS)};
            % Retrieve the list of all available mnemonics/commands for the TMCM-1160
            app.MNEMONICS = mnemonics_table();
            app.COMMANDS  = commands_table();
            app.CommandSelection.Items = [{''};fieldnames(app.COMMANDS)];
            app.CommandSelection.Value = '';
            % Retrieve the list of all available axis parameters for the TMCM-1160
            app.PARAMETERS = axis_parameters_table_r_w_e();
            % Set the identification number of the motor
            app.MOTOR_ID = 0;
            app.MotorSelection.Items = {num2str(app.MOTOR_ID)};
            % Set the selected type to empty
            app.TYPES = {''};
            app.TypeSelection.Items = {''};
            % Set boolean values to disable
            app.type_enabled = 0;
            app.run_file     = 0;
            app.trigger      = 0;
            app.save_to_file = 0;
            app.RadioButtons.SelectedObject.Value = 1;
            % Set the parameters for the plots of the measured data
            app.refresh_time = 10;
            app.window_time  = 1000;
            % Initialization of the plots axes
            app.position_plot     = line(app.PositionAxis,nan(1,app.window_time),nan(1,app.window_time));
            app.velocity_plot     = line(app.VelocityAxis,nan(1,app.window_time),nan(1,app.window_time));
            app.acceleration_plot = line(app.AccelerationAxis,nan(1,app.window_time),nan(1,app.window_time));
            % Default button visibility in the App
            app.DisconnectSerial.Enable     = 'off';
            app.StartCommand.Enable         = 'off';
            app.StopCommand.Enable          = 'off';
            app.EmergencyStopCommand.Enable = 'off';
            app.GenerateInstruction.Enable  = 'off';
            app.CaptureData.Enable          = 'off';
            app.RunFile.Enable              = 'off';
            app.Connectionstatus.Color      = 0.94*[1 1 1];
            % Clear the file path
            if ~isempty(app.FilePathText.Value)
                app.FilePathText.Value = '';
            end
        end

        % Button pushed function: SearchPorts
        function searchComPorts(app, event)
            % Clear the output error log text area
            app.ConnectionLog.Value = '';
            % Retrieve the list of all available COM ports on the computer
            app.ComPortSelection.Items = get_com_ports();
        end

        % Button pushed function: ConnectSerial
        function ConnectSerialPushed(app, event)
            % Constants
            CONNECTION_OK = 'The COM port connection has been established successfully.';
            CONNECTION_KO = 'No available COM port has been selected. Click on the ''Search'' button to get the status of the available COM ports.';
            % Clear the output error log text area
            app.ConnectionLog.Value = '';
            % Creation of the serial object
            try
                % Start the serial port session
                app.COM_obj = serial(app.ComPortSelection.Value,'BaudRate',str2double(app.BaudrateSelection.Value),'Timeout',2,'Databits',8,'StopBits',1);
                fopen(app.COM_obj);
                app.ConnectionLog.FontColor = [0 0.75 0];
                app.DisconnectSerial.Enable = 'on';
                app.ConnectSerial.Enable    = 'off';
                app.CaptureData.Enable      = 'on';
                app.ConnectionLog.Value     = CONNECTION_OK;
                app.Connectionstatus.Color  = [0 1 0];
            catch
                app.ConnectionLog.FontColor = [0.75 0 0];
                app.DisconnectSerial.Enable = 'off';
                app.ConnectSerial.Enable    = 'on';
                app.CaptureData.Enable      = 'off';
                app.ConnectionLog.Value     = CONNECTION_KO;
                app.Connectionstatus.Color  = [1 0 0];
            end
        end

        % Button pushed function: DisconnectSerial
        function DisconnectSerialPushed(app, event)
            fclose(app.COM_obj);
            app.ConnectionLog.FontColor = [0 0.75 0];
            app.ConnectionLog.Value     = 'The COM port connection has been closed successfully.';
            app.startupFcn();
            app.DisconnectSerial.Enable = 'off';
            app.ConnectSerial.Enable    = 'on';
            app.FileLogText.Value = '';
        end

        % Button pushed function: StartCommand
        function StartCommandPushed(app, event)
            app.StopCommand.Enable = 'on';
            [error_code,message,data] = send_serial_command(app.COM_obj,app.ADDRESS,...
                                                            getfield(app.COMMANDS,app.CommandSelection.Value),...
                                                            getfield(app.TYPES(1),app.TypeSelection.Value),...
                                                            app.MOTOR_ID,app.ValueSelection.Value,...
                                                            app.WaitTimeSelection.Value);
            if (error_code == 0)
                app.MessageLogText.FontColor = [0 0.75 0];
                app.Connectionstatus.Color   = [0 1 0];
            else
                app.MessageLogText.FontColor = [0.75 0 0];
                app.Connectionstatus.Color   = [1 0 0];
            end
            app.MessageLogText.Value = {message};
            app.ResultText.Value     = {num2str(data)};            
        end

        % Button pushed function: EmergencyStopCommand, StopCommand
        function StopCommandPushed(app, event)
            [error_code,message,data] = send_serial_command(app.COM_obj,app.ADDRESS,app.COMMANDS(1).MOTOR_STOP,0,app.MOTOR_ID,0,0);
            % Adapt the behavior depending from where the stop button has been pressed
            if strcmp(event.Source.Parent.Title,'File Mode')
                app.run_file = 0;
                app.EmergencyStopCommand.Enable = 'off';
                app.FileLogText.Value = {'An emergency stop has been activated by the user.'};
            else
                if (error_code == 0)
                    app.MessageLogText.FontColor = [0 0.75 0];
                    app.Connectionstatus.Color   = [0 1 0];
                else
                    app.MessageLogText.FontColor = [0.75 0 0];
                    app.Connectionstatus.Color   = [1 0 0];
                end
                app.StopCommand.Enable   = 'off';
                app.MessageLogText.Value = {message};
                app.ResultText.Value     = {num2str(data)};
            end
        end

        % Button pushed function: BrowseFile
        function BrowsePushed(app, event)
            % Clear the output log text area
            app.FileLogText.Value = '';
            % Retrieve the path to the text file to process
            [file_error,app.file_path] = ask_path(app.file_path);
            % Check if the path to the file exists
            if (file_error ~= 0)
                app.FilePathText.Value = 'Path does not exist. Please, select a correct file''s location.';
            else
                app.FilePathText.Value = app.file_path;
                app.RunFile.Enable = 'on';
            end
        end

        % Button pushed function: RunFile
        function RunSequencePushed(app, event)
            % An instructions file will be processed
            app.run_file = 1;
            app.trigger  = 1;
            app.CaptureData.Text     = 'Stop capture';
            app.RadioButtons.Visible = 'off';
            app.CaptureData.Enable   = 'off';
            app.EmergencyStopCommand.Enable = 'on';
            % Clear the output log text area
            app.FileLogText.Value        = '';
            % Launch the execution of the instructions set
            if ~isempty(app.COM_obj)
                app.FileLogText.Value = run_and_or_monitor(app);
            else
                app.FileLogText.Value = ['The serial connection has not been created.' newline 'Please set it up in the connection tab.'];
            end
            app.EmergencyStopCommand.Enable = 'off';
        end

        % Button pushed function: CaptureData
        function CaptureDataPushed(app, event)
            % Negate the trigger value
            app.trigger = ~app.trigger;
            % Update the button display accordingly
            if (app.trigger == 0)
                app.CaptureData.Text     = 'Start capture';
                app.RadioButtons.Visible = 'on';
            else
                app.CaptureData.Text     = 'Stop capture';
                app.RadioButtons.Visible = 'off';
                % Re-initialization of the plots axes
                app.PositionAxis.cla; app.VelocityAxis.cla; app.AccelerationAxis.cla;
                app.position_plot     = line(app.PositionAxis,nan(1,app.window_time),nan(1,app.window_time));
                app.velocity_plot     = line(app.VelocityAxis,nan(1,app.window_time),nan(1,app.window_time));
                app.acceleration_plot = line(app.AccelerationAxis,nan(1,app.window_time),nan(1,app.window_time));                
                % Monitor data if asked for
                app.FileLogText.Value = run_and_or_monitor(app);
            end
        end

        % Value changed function: TypeSelection
        function TypeSelectionValueChangeBoundaries(app, event)
            % Get boundaries from the selected parameter/type
            min_limit = app.TYPES(2).(app.TypeSelection.Value);
            max_limit = app.TYPES(3).(app.TypeSelection.Value);
            % Dynamically adapts the boundaries of the value to send
            app.ValueSelection.Limits = [min_limit max_limit];
        end

        % Value changed function: CommandSelection
        function CommandSelectionUpdateTypeAndValue(app, event)
            app.CommandSelection.Items = fieldnames(app.COMMANDS);
            % Dynamically adapts the boundaries of the value to send
            min_limit = app.COMMANDS(2).(app.CommandSelection.Value);
            max_limit = app.COMMANDS(3).(app.CommandSelection.Value);
            app.ValueSelection.Limits = [min_limit max_limit];
            % Dynamically provides parameters selection to the field type
            app.type_enabled = app.COMMANDS(4).(app.CommandSelection.Value);
            app.TYPES = get_type_fields(app.type_enabled);
            app.TypeSelection.Items = fieldnames(app.TYPES);
            % Activate the Start button to execute the command
            app.StartCommand.Enable = 'on';
            app.GenerateInstruction.Enable = 'on';
        end

        % Button pushed function: GenerateInstruction
        function GenerateCommandInstructionCode(app, event)
            mnemonic_decimal_value = getfield(app.COMMANDS,app.CommandSelection.Value);
            instruction_code_cell  = [mnemonics_acronyms(mnemonic_decimal_value) ' ',...
                                      num2str(app.AddressSelection.Value) ' ',...
                                      num2str(app.TYPES(1).(app.TypeSelection.Value)) ' ',...
                                      num2str(app.MOTOR_ID) ' ',...
                                      num2str(app.ValueSelection.Value) ' ',...
                                      num2str(app.WaitTimeSelection.Value)];
            app.CodeText.Value = {sprintf('%s',instruction_code_cell{:})};
        end

        % Selection changed function: RadioButtons
        function RadioButtonsSaveCapturedData(app, event)
            app.save_to_file = app.ButtonYes.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnectionTab = uitab(app.TabGroup);
            app.ConnectionTab.Title = 'Connection';
            app.SerialPortProtocols = uilabel(app.ConnectionTab);
            app.SerialPortProtocols.Text = 'RS232/RS485';
            app.SerialPort = uilabel(app.ConnectionTab);
            app.SerialPort.Text = 'Serial port:';
            app.SearchPorts = uibutton(app.ConnectionTab, 'push');
            app.SearchPorts.ButtonPushedFcn = createCallbackFcn(app, @searchComPorts, true);
            app.SearchPorts.Text = 'Search';
            app.ComPortSelectionLabel = uilabel(app.ConnectionTab);
            app.ComPortSelectionLabel.Text = 'Selection:';
            app.ComPortSelection = uidropdown(app.ConnectionTab);
            app.ComPortSelection.Items = {};
            app.ComPortSelection.Value = {};
            app.BaudrateSelectionLabel = uilabel(app.ConnectionTab);
            app.BaudrateSelectionLabel.Text = 'Baud rate:';
            app.BaudrateSelection = uidropdown(app.ConnectionTab);
            app.BaudrateSelection.Items = {'4800', '9600', '14400', '19200', '38400', '57600', '76800', '115200', '230400', '921600'};
            app.BaudrateSelection.Value = '4800';
            app.ConnectSerial = uibutton(app.ConnectionTab, 'push');
            app.ConnectSerial.ButtonPushedFcn = createCallbackFcn(app, @ConnectSerialPushed, true);
            app.ConnectSerial.Text = 'Connect';
            app.DisconnectSerial = uibutton(app.ConnectionTab, 'push');
            app.DisconnectSerial.ButtonPushedFcn = createCallbackFcn(app, @DisconnectSerialPushed, true);
            app.DisconnectSerial.Text = 'Disconnect';
            app.ProcedureTextLabel = uilabel(app.ConnectionTab);
            app.ProcedureTextLabel.Text = 'Procedure';
            app.ProcedureText = uitextarea(app.ConnectionTab);
            app.ProcedureText.Value = {'1) Press the "Search" button to find available COM ports'; '2) Select one of the available port'; '3) Choose the desired communication rate'; '4) Click on "Connect" to start the serial connection'; '5) Click on another tab to start moving the motor'; '6) Click on "Disconnect" to close the serial connection'};
            app.ConnectionLogLabel = uilabel(app.ConnectionTab);
            app.ConnectionLogLabel.Text = 'Connection log';
            app.ConnectionLog = uitextarea(app.ConnectionTab);
            app.ConnectionstatusLampLabel = uilabel(app.ConnectionTab);
            app.ConnectionstatusLampLabel.Text = 'Connection status:';
            app.Connectionstatus = uilamp(app.ConnectionTab);
            app.DirectModeTab = uitab(app.TabGroup);
            app.DirectModeTab.Title = 'Direct Mode';
            app.SerialMessage = uilabel(app.DirectModeTab);
            app.SerialMessage.Text = 'Serial message';
            app.MessageTextLabel = uilabel(app.DirectModeTab);
            app.MessageTextLabel.Text = 'Message';
            app.MessageText = uitextarea(app.DirectModeTab);
            app.MessageText.Value = {'1) The module address is fixed and set to ''1'''; '2) Select one of the available command'; '3) Select a type if the command permits'; '4) The number of a single motor is fixed and set to ''0'''; '5) Select the value to send for the selected command'; '6) Enter a wait time after the command execution'};
            app.AddressSelectionLabel = uilabel(app.DirectModeTab);
            app.AddressSelectionLabel.Text = 'Address:';
            app.AddressSelection = uidropdown(app.DirectModeTab);
            app.AddressSelection.Items = {};
            app.AddressSelection.Value = {};
            app.CommandSelectionLabel = uilabel(app.DirectModeTab);
            app.CommandSelectionLabel.Text = 'Command:';
            app.CommandSelection = uidropdown(app.DirectModeTab);
            app.CommandSelection.Items = {};
            app.CommandSelection.ValueChangedFcn = createCallbackFcn(app, @CommandSelectionUpdateTypeAndValue, true);
            app.CommandSelection.Value = {};
            app.TypeSelectionLabel = uilabel(app.DirectModeTab);
            app.TypeSelectionLabel.Text = 'Type:';
            app.TypeSelection = uidropdown(app.DirectModeTab);
            app.TypeSelection.Items = {};
            app.TypeSelection.ValueChangedFcn = createCallbackFcn(app, @TypeSelectionValueChangeBoundaries, true);
            app.TypeSelection.Value = {};
            app.MotorSelectionLabel = uilabel(app.DirectModeTab);
            app.MotorSelectionLabel.Text = 'Motor:';
            app.MotorSelection = uidropdown(app.DirectModeTab);
            app.MotorSelection.Items = {};
            app.MotorSelection.Value = {};
            app.ValueSelectionLabel = uilabel(app.DirectModeTab);
            app.ValueSelectionLabel.Text = 'Value:';
            app.ValueSelection = uispinner(app.DirectModeTab);
            app.ValueSelection.Limits = [0 1];
            app.ValueSelection.ValueDisplayFormat = '%.0f';
            app.ResultTextLabel = uilabel(app.DirectModeTab);
            app.ResultTextLabel.Text = 'Result:';
            app.ResultText = uitextarea(app.DirectModeTab);
            app.TimeSelectionLabel = uilabel(app.DirectModeTab);
            app.TimeSelectionLabel.Text = 'Wait time:';
            app.WaitTimeSelection = uispinner(app.DirectModeTab);
            app.WaitTimeSelection.Limits = [0 Inf];
            app.WaitTimeSelection.ValueDisplayFormat = '%.0f';
            app.StartCommand = uibutton(app.DirectModeTab, 'push');
            app.StartCommand.ButtonPushedFcn = createCallbackFcn(app, @StartCommandPushed, true);
            app.StartCommand.Text = 'Start';
            app.StopCommand = uibutton(app.DirectModeTab, 'push');
            app.StopCommand.ButtonPushedFcn = createCallbackFcn(app, @StopCommandPushed, true);
            app.StopCommand.Text = 'Stop';
            app.MessageLogTextLabel = uilabel(app.DirectModeTab);
            app.MessageLogTextLabel.Text = 'Message log';
            app.MessageLogText = uitextarea(app.DirectModeTab);
            app.Instruction = uilabel(app.DirectModeTab);
            app.Instruction.Text = 'Instruction:';
            app.GenerateInstruction = uibutton(app.DirectModeTab, 'push');
            app.GenerateInstruction.ButtonPushedFcn = createCallbackFcn(app, @GenerateCommandInstructionCode, true);
            app.GenerateInstruction.Text = 'Generate';
            app.CodeTextLabel = uilabel(app.DirectModeTab);
            app.CodeTextLabel.Text = 'Code:';
            app.CodeText = uitextarea(app.DirectModeTab);
            app.InstructionStructure = uilabel(app.DirectModeTab);
            app.InstructionStructure.Text = 'Structure:   CMD   MODULE   TYPE   MOTOR   VALUE   WAIT_TIME';
            app.FileModeTab = uitab(app.TabGroup);
            app.FileModeTab.Title = 'File Mode';
            app.TextFileToProcess = uilabel(app.FileModeTab);
            app.TextFileToProcess.Text = 'File to process';
            app.BrowseFile = uibutton(app.FileModeTab, 'push');
            app.BrowseFile.ButtonPushedFcn = createCallbackFcn(app, @BrowsePushed, true);
            app.BrowseFile.Text = 'Browse';
            app.FilepathTextLabel = uilabel(app.FileModeTab);
            app.FilepathTextLabel.Text = 'File path:';
            app.FilePathText = uitextarea(app.FileModeTab);
            app.InstructionsToRun = uilabel(app.FileModeTab);
            app.InstructionsToRun.Text = 'Start playing the sequence of instructions';
            app.RunFile = uibutton(app.FileModeTab, 'push');
            app.RunFile.ButtonPushedFcn = createCallbackFcn(app, @RunSequencePushed, true);
            app.RunFile.Text = 'Run File';
            app.MeasurementTextLabel = uilabel(app.FileModeTab);
            app.MeasurementTextLabel.Text = 'Measurement:';
            app.MeasurementText = uitextarea(app.FileModeTab);
            app.FileLogLabel = uilabel(app.FileModeTab);
            app.FileLogLabel.Text = 'File log';
            app.FileLogText = uitextarea(app.FileModeTab);
            app.InstructionIDLabel = uilabel(app.FileModeTab);
            app.InstructionIDLabel.Text = 'Instruction ID:';
            app.InstructionIDText = uitextarea(app.FileModeTab);
            app.EmergencyStopCommand = uibutton(app.FileModeTab, 'push');
            app.EmergencyStopCommand.ButtonPushedFcn = createCallbackFcn(app, @StopCommandPushed, true);
            app.EmergencyStopCommand.Text = 'Stop';
            app.EmergencyStopLabel = uilabel(app.FileModeTab);
            app.EmergencyStopLabel.Text = 'Emergency stop';
            app.MonitoringTab = uitab(app.TabGroup);
            app.MonitoringTab.Title = 'Monitoring';
            app.PositionAxis = uiaxes(app.MonitoringTab);
            title(app.PositionAxis, 'Position')
            ylabel(app.PositionAxis, 'Step [1]')
            app.VelocityAxis = uiaxes(app.MonitoringTab);
            title(app.VelocityAxis, 'Velocity')
            ylabel(app.VelocityAxis, 'Speed [1]')
            app.AccelerationAxis = uiaxes(app.MonitoringTab);
            title(app.AccelerationAxis, 'Acceleration')
            xlabel(app.AccelerationAxis, 'Time [s]')
            ylabel(app.AccelerationAxis, 'Acc [1]')
            app.Datamonitoring = uilabel(app.MonitoringTab);
            app.Datamonitoring.Text = 'Data monitoring:';
            app.CaptureData = uibutton(app.MonitoringTab, 'push');
            app.CaptureData.ButtonPushedFcn = createCallbackFcn(app, @CaptureDataPushed, true);
            app.CaptureData.Text = 'Start capture';
            app.RadioButtons = uibuttongroup(app.MonitoringTab);
            app.RadioButtons.SelectionChangedFcn = createCallbackFcn(app, @RadioButtonsSaveCapturedData, true);
            app.RadioButtons.Title = 'Save captured data';
            app.ButtonYes = uiradiobutton(app.RadioButtons);
            app.ButtonYes.Text = 'Yes';
            app.ButtonNo = uiradiobutton(app.RadioButtons);
            app.ButtonNo.Text = 'No';
            app.ButtonNo.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Control_Panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end